classdef GUI_LYU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure1                       matlab.ui.Figure
        AddFileButton                   matlab.ui.control.Button
        EntriesListBoxLabel             matlab.ui.control.Label
        EntriesListBox                  matlab.ui.control.ListBox
        DeleteFileButton                matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        MoveDownButton                  matlab.ui.control.Button
        AliasforselectedfileEditFieldLabel  matlab.ui.control.Label
        AliasforselectedfileEditField   matlab.ui.control.EditField
        OpensecondGUIButton             matlab.ui.control.Button
        CursorvsTargetButton            matlab.ui.control.Button
        FigureGenerationLabel           matlab.ui.control.Label
        LoadOrganizeFilesLabel          matlab.ui.control.Label
        CompletionRatevsSecondsButton   matlab.ui.control.Button
        SpeedHistogramButton            matlab.ui.control.Button
        PostureMatchingMetricsButton    matlab.ui.control.Button
        VelocitySpeedProfileButton      matlab.ui.control.Button
        CumulativeFiguresLabel          matlab.ui.control.Label
        CumulativeCompletionRateButton  matlab.ui.control.Button
        CumulativeSpeedHistogramButton  matlab.ui.control.Button
        CumulativeMetricBoxplotsButton  matlab.ui.control.Button
        CumulativeVelocityProfileButton  matlab.ui.control.Button
        AverageVelocityProfilePlotButton  matlab.ui.control.Button
        GotuserinputLabel               matlab.ui.control.Label
        InfoaboutselectedfileLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NameofselectedfileLabel         matlab.ui.control.Label
        SavealldatatodesktopButton      matlab.ui.control.Button
    end


    properties (Access = public)
        Files % The log files
        %currentFile % The current log file (used when adding log files)
        numfiles = 0;
        DialogApp; % dialog app
        screenSize = get(0,'ScreenSize');
        figurePosition;        
        % properties involving plotting
                

    end

    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddFileButton
        function AddFileButtonPushed(app, event)
            global x
            global S
            currentFile = uigetfile;
            x.UIFigure1.Visible = 'off';
            x.UIFigure1.Visible = 'on';
            
            if currentFile ~= 0 % if the user selected a file
                x.numfiles = x.numfiles + 1;
                numberOfFiles = x.numfiles;
                S(numberOfFiles).name = currentFile;
                S(numberOfFiles).alias = currentFile;
                S(numberOfFiles).data = load(currentFile);
                oldstr = get(x.EntriesListBox,'Items'); % The string as it is now.
                %set(app.EntriesListBox,'Items',{currentFile,oldstr{:}});  % Put the new file name on top
                set(x.EntriesListBox,'Items',{oldstr{:},currentFile});  % Put the new file name on the bottom.
            end
            S(numberOfFiles).loaded = false; % the user hasn't added the other info needed for plotting yet
           
        end

        % Button pushed function: DeleteFileButton
        function DeleteFileButtonPushed(app, event)
            global x
            global S
            selected = get(x.EntriesListBox,{'Items', 'Value'});
            if ~isempty(selected{1})
                [~,index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                x.EntriesListBox.Items(index) = []; % delete from list box
                x.numfiles = x.numfiles - 1;
                S(index) = [];% delete from structure S
            end
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            global x
            selected = get(x.EntriesListBox,{'Items', 'Value'});
            if ~isempty(selected{1})
                [~,index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                if index > 1
                    temp = x.EntriesListBox.Items(index - 1);
                    x.EntriesListBox.Items(index - 1) = x.EntriesListBox.Items(index);
                    x.EntriesListBox.Items(index) = temp;               
                end
                
            end
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            global x
            selected = get(x.EntriesListBox,{'Items', 'Value'});
            if ~isempty(selected{1})
                [~,index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                if index < x.numfiles
                    temp = x.EntriesListBox.Items(index + 1);
                    x.EntriesListBox.Items(index + 1) = x.EntriesListBox.Items(index);
                    x.EntriesListBox.Items(index) = temp; % swap in list box
                    
                end
            end
        end

        % Value changed function: AliasforselectedfileEditField
        function AliasforselectedfileEditFieldValueChanged(app, event)
           global x
           global S
            value = x.AliasforselectedfileEditField.Value;
            selected = get(x.EntriesListBox,{'Items', 'Value'});
            if ~isempty(selected{1})
                [~,index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                %temp=str2cell(value);
                x.EntriesListBox.Items(index) = {value};
                S(index).alias = {value};
            end
        end

        % Value changed function: EntriesListBox
        function EntriesListBoxValueChanged(app, event)
            global x
            global S
            x.EntriesListBox.Multiselect = 'on';     
            selected = x.EntriesListBox.Value{1};
            if size(x.EntriesListBox.Value,2) == 1 && ~isempty(selected)
                x.AliasforselectedfileEditField.Value = selected; % display the alias (or the original name if there is no alias) in the 'alias for selected field' box
                [~, index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                x.Label.Text = S(index).name;
                if S(index).loaded == true
                    x.GotuserinputLabel.Text = 'Got user input?: yes';
                else
                    x.GotuserinputLabel.Text = 'Got user input? no';
                end
            end
        end

        % Button pushed function: OpensecondGUIButton
        function OpensecondGUIButtonPushed(app, event)
            global y
            global x
            %selected = get(x.EntriesListBox,{'Items', 'Value'});
            
            [~, indicies] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
            if size(indicies,2) ~= 1
                errordlg('error: please select one log file');
            else
                index = indicies;
            end
            
            y=GUI_LYU2;
            y.index = index;
        end

        % Close request function: UIFigure1
        function UIFigure1CloseRequest(app, event)
            delete(app)
            global x
            delete(x)
        end

        % Button pushed function: CursorvsTargetButton
        function CursorvsTargetButtonPushed(app, event)
            global x 
            global S
            
            x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
            %h = figure('Name',[x.EntriesListBox.Value ' Cursor vs Targets'],'Position', x.figurePosition);
            [~, index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
            % make sure the data for this index exists
            if size(index) > 1
                errordlg('select one log file');
            end
            % make sure the data for this index exists
            if ~S(index).loaded
                errordlg('error: data incomplete');
            end
            S(index).tend = zeros(1, S(index).nblocks+1);
            h = figure('Name',[char(x.EntriesListBox.Value) ' Cursor vs Targets'],'Position', x.figurePosition);
                % Each dof is a separate subplot
           for k=1:S(index).ndof
                    % Plot normalized curves (all 0-100%)
                    S(index).toff = 0;
                    hold on;
                    for i = 1:S(index).nblocks
                        for j = 1:S(index).ntrials(i)-(S(index).starttrials(i)-1)
                            subplot(S(index).ndof,1,k)
                            %plot targets +/- tolerance as filled areas JML
                            jbfill(S(index).ct{i,j}+S(index).toff, S(index).nctarget{i,j}(k,:)+S(index).difficulty(i), S(index).nctarget{i,j}(k,:)-S(index).difficulty(i), [.35 .35 .75], 'none'); 
                            hold on;
                            %plot cursor signal
                            plot(S(index).ct{i,j}+S(index).toff, S(index).nccursor{i,j}(k,:), 'k');
                            %plot 'X' at end of target if unsuccessful
                            plot(S(index).ct{i,j}+S(index).toff, S(index).ctout{i,j}.*S(index).nctarget{i,j}(k,:), 'rx');
                            %adjust toffset by length of current trial
                            S(index).toff = S(index).ct{i,j}(end)+S(index).toff;
                        end
                        if k==1
                            title(['Cursor vs Targets for ' x.EntriesListBox.Value]);
                            %save the end time for the block (same for each dof)
                            S(index).tend(i+1)=S(index).toff;
                            %label the blocks (only above first subplot)
                            text(mean([S(index).tend(i) S(index).tend(i+1)]), 110, num2str(i), 'HorizontalAlignment', 'Center', 'Color', 'r')
                        end
                         %mark the beginning and end of each block on each subplot
                         
                         plot([S(index).tend(i+1) S(index).tend(i+1)], [0 100], 'r');
                    end
                    %plot scale indicators for time and dof angles
                    plot([S(index).toff+10 S(index).toff+10], [0 100], 'k', 'LineWidth', 3); 

                    if (S(index).ROM(1,k)==0) && (S(index).ROM(2,k)==100)
                        text(S(index).toff+20, 0, [num2str(S(index).ROM(1,k)) '%'], 'VerticalAlignment', 'Middle', 'HorizontalAlignment', 'Left');
                        text(S(index).toff+20, 100, [num2str(S(index).ROM(2,k)) '%'], 'VerticalAlignment', 'Middle', 'HorizontalAlignment', 'Left');
                    else
                        text(S(index).toff+20, 0, [num2str(S(index).ROM(1,k)) '\circ'], 'VerticalAlignment', 'Middle', 'HorizontalAlignment', 'Left');
                        text(S(index).toff+20, 100, [num2str(S(index).ROM(2,k)) '\circ'], 'VerticalAlignment', 'Middle', 'HorizontalAlignment', 'Left');
                    end

                    %make axes slightly bigger than necessary to accomodate scale
                    %indicators (add space to right and below)
                    axis([0 S(index).toff+20 0-20 100]);  
                    axis off;
                    plot([0 60], [-10 -10], 'k', 'LineWidth', 3); 
                    text(30, -15, '60s', 'VerticalAlignment', 'Top', 'HorizontalAlignment', 'Center')
                    %label the subplot (can't use ylabel, because axis is hidden)
                    text(0, 50, S(index).dofLabels(k), 'VerticalAlignment', 'Middle', 'HorizontalAlignment', 'Right', 'FontWeight', 'bold')
                    %set background color to white (default is gray)
                    set(h, 'Color', [1 1 1])
                    hold off;
           end
           
%                 if saveFigs
%                     if iscell(saveFormat)
%                         for i = 1:length(saveFormat)
%                             saveas(h,[savingDirectory fileSlash strrep(get(h,'Name'),'/','_') saveFormat{i}]);
%                         end
%                     else
%                         saveas(h,[savingDirectory fileSlash strrep(get(h,'Name'),'/','_') saveFormat]);
%                     end
%                 end
%                 if ~displayFigs
%                     close(h)
%                 end
           

        end

        % Callback function
        function DOF1LabelEditFieldValueChanged(app, event)
          global x
          S(index).dofLabels{1} = x.DOF1LabelEditField.Value
            
        end

        % Callback function
        function DOF2LabelEditFieldValueChanged(app, event)
           global x
           S(index).dofLabels{2} = x.DOF2LabelEditField.Value;
        end

        % Callback function
        function DOF3LabelEditFieldValueChanged(app, event)
            global x
            S(index).dofLabels{3} = app.DOF3LabelEditField.Value;
        end

        % Callback function
        function DOF4LabelEditFieldValueChanged(app, event)
            global x
            S(index).dofLabels{4} = x.DOF4LabelEditField.Value;
        end

        % Callback function
        function DOF5LabelEditFieldValueChanged(app, event)
            global x
            S(index).dofLabels{5} = x.DOF5LabelEditField.Value;
        end

        % Callback function
        function DOF6LabelEditFieldValueChanged(app, event)
            global x
            S(index).dofLabels{6} = x.DOF6LabelEditField.Value;
        end

        % Callback function
        function DOF1LowerBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(1,1) = x.DOF1LowerBoundEditField.Value;
        end

        % Callback function
        function DOF1HigherBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(2,1) = x.DOF1HigherBoundEditField.Value;
        end

        % Callback function
        function DOF2LowerBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(1,2) = x.DOF2LowerBoundEditField.Value;
        end

        % Callback function
        function DOF2HigherBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(2,2) = x.DOF2HigherBoundEditField.Value;
        end

        % Callback function
        function DOF3LowerBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(1,3) = x.DOF3LowerBoundEditField.Value;
        end

        % Callback function
        function DOF3HigherBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(2,3) = x.DOF3HigherBoundEditField.Value;
        end

        % Callback function
        function DOF4LowerBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(1,4) = x.DOF4LowerBoundEditField.Value;
        end

        % Callback function
        function DOF4HigherBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(2,4) = x.DOF4HigherBoundEditField.Value;
        end

        % Callback function
        function DOF5LowerBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(1,5) = x.DOF5LowerBoundEditField.Value;
        end

        % Callback function
        function DOF5HigherBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(2,5) = x.DOF5HigherBoundEditField.Value;
        end

        % Callback function
        function DOF6LowerBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(1,6) = x.DOF6HigherBoundEditField.Value;
        end

        % Callback function
        function DOF6HigherBoundEditFieldValueChanged(app, event)
            global x
            x.ROM(2,6) = x.DOF6HigherBoundEditField.Value;
        end

        % Callback function
        function NumbersofDOFEditFieldValueChanged(app, event)
            global x
            x.ndof = x.NumbersofDOFEditField.Value;
%             if (x.ndof<1)
%                 error('Unsupported number of Degrees of Freedom chosen.');
%             end
        end

        % Button pushed function: SpeedHistogramButton
        function SpeedHistogramButtonPushed(app, event)
            global x 
            global S
            
            x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
            [~, index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
            if size(index) > 1
                errordlg('select one log file');
            end
            % make sure the data for this index exists
            if ~S(index).loaded
                errordlg('error: data incomplete');
            end
            S(index).tend = zeros(1, S(index).nblocks+1);
            h = figure('Name',[char(x.EntriesListBox.Value) ' Speed Histogram'],'Position', x.figurePosition);
            S(index).oneDimensionalAvgedMovementSpeeds = [];
            for i = 1:S(index).nblocks
                    for j = 1:S(index).ntrials(i)-(S(index).starttrials(i)-1)
                        S(index).oneDimensionalAvgedMovementSpeeds =[S(index).oneDimensionalAvgedMovementSpeeds mean(S(index).movementSpeeds{i,j}(:,:),1)];
                    end
            end
            h2 = histogram(S(index).oneDimensionalAvgedMovementSpeeds);
            h2.Normalization = 'probability';
            legend(x.EntriesListBox.Value);
            ylabel('Probability');
            xlabel('% of Range of Motion per Second (as Averaged Across All DOFs)');
            title([x.EntriesListBox.Value, ' Movement Speed Histogram']);
            clear oneDimensionalAvgedMovementSpeeds;
            
        end

        % Button pushed function: CumulativeSpeedHistogramButton
        function CumulativeSpeedHistogramButtonPushed(app, event)
            global x
            global S       
            x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
            cumulativeSpeedHistogramsFig = figure('Name','Cumulative Movement Speed Histograms','Position',x.figurePosition);
            set(0, 'CurrentFigure', cumulativeSpeedHistogramsFig);
            legend(x.EntriesListBox.Value);
            cumulativeSpeedHistogramsLegendHandles = [];
            cumulativeSpeedHistogramsLegendStrings = {};
            for numlog = 1:size(x.EntriesListBox.Value,2)
                [~, indicies] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                index = indicies(numlog);       
                oneDimensionalAvgedMovementSpeeds = [];       
                for i = 1:S(index).nblocks
                    for j = 1:S(index).ntrials(i)-(S(index).starttrials(i)-1)
                        oneDimensionalAvgedMovementSpeeds =[oneDimensionalAvgedMovementSpeeds mean(S(index).movementSpeeds{i,j}(:,:),1)];
                    end
                end
                histogram(oneDimensionalAvgedMovementSpeeds);
              
                hold on;
            end
        end

        % Button pushed function: CompletionRatevsSecondsButton
        function CompletionRatevsSecondsButtonPushed(app, event)
                global S
                global x
                [~, index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                if size(index) > 1
                    errordlg('select one log file');
                end
                % make sure the data for this index exists
                if ~S(index).loaded
                    errordlg('error: data incomplete');
                end
                S(index).oneDimensionalTimeToTarget = [];
                for i = 1:S(index).nblocks
                    S(index).oneDimensionalTimeToTarget =[S(index).oneDimensionalTimeToTarget S(index).timetotarget(i,1:S(index).ntrials(i)-(S(index).starttrials(i)-1))];
                end
                sortedtimetotarget = sort(S(index).oneDimensionalTimeToTarget);
                y = zeros(1,sum(S(index).ntrials-(S(index).starttrials-1)));
                if length(sortedtimetotarget)~=length(y)
                    error('Dimensionality Error');
                end
                for i = 1:length(y)
                    y(i) = 100*(sum((sortedtimetotarget<=sortedtimetotarget(i)))/length(y));
                end
                clear oneDimensionalTimeToTarget;  
                x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
                h = figure('Name', [char(x.EntriesListBox.Value) ' Completion Rate vs Seconds'],'Position', x.figurePosition);              
                scatter(sortedtimetotarget,y);
                legend(char(x.EntriesListBox.Value));
                axis([0 S(index).timeout 0 100]);
                ylabel('% Trials Complete');
                xlabel('Seconds');
                title([x.EntriesListBox.Value ' Completion Rate']);
                
                
        end

        % Button pushed function: CumulativeMetricBoxplotsButton
        function CumulativeMetricBoxplotsButtonPushed(app, event)
            % : save the overshoots, path efficiency, trial time to S
            % : make button to save the S to their desktop
              global x
              global S
              x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
              cumulativeMetricBoxplotsFig = figure('Name','Cumulative Posture Matching Metrics','Position',x.figurePosition);
              for numlog = 1:size(x.EntriesListBox.Value,2)
                [~, indicies] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                index = indicies(numlog);
                PostureMatchingTotalMetrics(:,1) = [{'Metrics'};{'Mean Path Efficiency'};{'StDev Path Efficiency'};{'StErr Path Efficiency'};...
                                                        {'Mean Time To Target'};{'StDev Time To Target'};{'StErr Time To Target'};...
                                                        {'Mean Movement Speed'};{'StDev Movement Speed'};{'StErr MovementSpeed'};...
                                                        {'Mean Overshoot'};{'StDev Overshoot'};{'StErr Overshoot'}; {'Completion Rate'}; {'Grouping'}; {'Group Name'}; {'N'};...
                                                        {'Trial Path Efficiencies'};{'Trial Overshoots'};{'Movement Speed Trial Averages'};{'Trial Movement Starts'};{'Trial Time to Targets'};{'DOF Labels'}];
                PostureMatchingTotalMetrics{1,end+1} = S(index).alias;
                PostureMatchingTotalMetrics{2,end} = S(index).meanpatheff;
                PostureMatchingTotalMetrics{3,end} = S(index).stdvpatheff;             
                PostureMatchingTotalMetrics{4,end} = S(index).stdvpatheff/ sqrt( sum( S(index).ntrials- ( S(index).starttrials-1 ) ));
                PostureMatchingTotalMetrics{5,end} = S(index).meantimetotarget;
                PostureMatchingTotalMetrics{6,end} = S(index).stdvtimetotarget;
                PostureMatchingTotalMetrics{7,end} = S(index).stdvtimetotarget/sqrt(sum(S(index).ntrials-(S(index).starttrials-1)));
                PostureMatchingTotalMetrics{8,end} = S(index).meanmovementspeed;
                PostureMatchingTotalMetrics{9,end} = S(index).stdvmovementspeed;
                PostureMatchingTotalMetrics{10,end} = S(index).stdvmovementspeed/sqrt(sum(S(index).ntrials-(S(index).starttrials-1)));
                PostureMatchingTotalMetrics{11,end} = S(index).meanovershoot;
                PostureMatchingTotalMetrics{12,end} = S(index).stdvovershoot;
                PostureMatchingTotalMetrics{13,end} = S(index).stdvovershoot/sqrt(sum(S(index).ntrials-(S(index).starttrials-1)));
                PostureMatchingTotalMetrics{14,end} = S(index).successrate;
                PostureMatchingTotalMetrics{17,end} = sum(S(index).ntrials-(S(index).starttrials-1));
                PostureMatchingTotalMetrics{18,end} = S(index).patheff;
                PostureMatchingTotalMetrics{19,end} = S(index).overshoots;
                PostureMatchingTotalMetrics{20,end} = S(index).movementSpeedTrialAverages;
                PostureMatchingTotalMetrics{21,end} = S(index).movementStart;
                PostureMatchingTotalMetrics{22,end} = S(index).timetotarget;
                PostureMatchingTotalMetrics{23,end} = S(index).dofLabels;   
                grouping = inputdlg({'What boxplot group is this data?'},'Boxplot Group Number',1,{'1'});
                grouping = sscanf(int2str(S(index).ndof),'%u',1);
                if (grouping<1)
                    error('Unsupported boxplot grouping chosen.');  %: Finish Grouping. 0 for no Grouping? So I can check Agonist/Antagonist, DOFs, and Subject. Check /Users/Hendrik/Documents/GraduateSchool/Summer2016/NIBIB/Figures/ANNFigures_11_19_2015/FigureMakingScript.m
                end
                if ~any(cell2mat(PostureMatchingTotalMetrics(15,2:end))==grouping)
                    groupName = inputdlg({'What is the title for this new boxplot group?'},'Boxplot Group Name',1,{'Agonist/Antagonist Control'});
                    PostureMatchingTotalMetrics{16,end} = groupName;
                else
                    groupNameIndices = find(cell2mat(PostureMatchingTotalMetrics(15,2:end))==grouping)+1;
                    PostureMatchingTotalMetrics{16,end} = PostureMatchingTotalMetrics{16,groupNameIndices(1)};
                end
                PostureMatchingTotalMetrics{15,end} = grouping;
                
              end
              set(0, 'CurrentFigure', cumulativeMetricBoxplotsFig)

            %PostureMatchingTotalMetrics{15,end} = grouping;
            %function handles = barweb(barvalues, errors, width, groupnames, bw_title, bw_xlabel, bw_ylabel, bw_colormap, gridstatus, bw_legend, error_sides, legend_type)


            % Group all information together
            legend = {};
            PathEffmeans = [];
            PathEfferrs = [];
            TrialTimemeans = [];
            TrialTimeerrs = [];
            MovementSpeedmeans = [];
            MovementSpeederrs = [];
            Overshootsmeans = [];
            Overshootserrs = [];
            SuccessRate = [];
            index = 1;
            for j = 2:size(PostureMatchingTotalMetrics,2)
                legend(index) = {PostureMatchingTotalMetrics{1,j}};
                PathEffmeans = [PathEffmeans cell2mat(PostureMatchingTotalMetrics(2,j))];
                PathEfferrs = [PathEfferrs cell2mat(PostureMatchingTotalMetrics(4,j))];
                TrialTimemeans = [TrialTimemeans cell2mat(PostureMatchingTotalMetrics(5,j))];
                TrialTimeerrs = [TrialTimeerrs cell2mat(PostureMatchingTotalMetrics(7,j))];
                MovementSpeedmeans = [MovementSpeedmeans cell2mat(PostureMatchingTotalMetrics(8,j))];
                MovementSpeederrs = [MovementSpeederrs cell2mat(PostureMatchingTotalMetrics(10,j))];
                Overshootsmeans = [Overshootsmeans cell2mat(PostureMatchingTotalMetrics(11,j))];
                Overshootserrs = [Overshootserrs cell2mat(PostureMatchingTotalMetrics(13,j))];
                SuccessRate = [SuccessRate cell2mat(PostureMatchingTotalMetrics(14,j))];
                index = index+1;
            end
            % Path Efficiency
            subplot(2,3,1);
            handle = barweb(PathEffmeans, PathEfferrs, [], [], 'Path Efficiency', [], '%',[],[],legend);
            % Percent Trial Time Needed
            subplot(2,3,2);
            handle = barweb(TrialTimemeans, TrialTimeerrs, [], [], 'Trial Time to Target', [], 's',[],[]);
            % Average Overall (MultiDOF) Movement Speed
            subplot(2,3,3);
            handle = barweb(MovementSpeedmeans, MovementSpeederrs, [], [], 'Average Movement Speed', [], '% of Range of Motion per Second (as Averaged Across All DOFs)',[],[]);
            % Average Overshoots per Trial for all DOFs
            subplot(2,3,4);
            handle = barweb(Overshootsmeans, Overshootserrs, [], [], 'Overshoots', [], 'Average Overshoots per Trial (Across All DOFs)',[],[]);
            % Success Rate
            subplot(2,3,5);
            handle = barweb(SuccessRate, zeros(size(SuccessRate)), [], [], 'Success Rate', [], '%',[],[]);
            
        end

        % Button pushed function: PostureMatchingMetricsButton
        function PostureMatchingMetricsButtonPushed(app, event)
            global S
            global x
            [~, index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
            if size(index) > 1
                errordlg('select one log file');
            end
            % make sure the data for this index exists
            if ~(S(index).loaded)
                errordlg('error: data incomplete');
            end
            x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
            h = figure('Name',[char(x.EntriesListBox.Value) ' Posture Matching Metrics'],'Position', x.figurePosition);
            overshootsPerDOFmeans = NaN(1,S(index).ndof);
            overshootsPerDOFstderr = NaN(1,S(index).ndof);
            movementStartPerDOFmeans = NaN(1,S(index).ndof);
            movementStartPerDOFstderr = NaN(1,S(index).ndof);
            movementSpeedAveragePerDOFmeans = NaN(1,S(index).ndof);
            movementSpeedAveragePerDOFstderr = NaN(1,S(index).ndof);        
            for k = 1:S(index).ndof
                currentDOFovershoots = [];
                currentDOFMovementStart = [];
                currentDOFMovementSpeedAverages = [];
                for i = 1:S(index).nblocks
                    for j = 1:S(index).ntrials(i)-(S(index).starttrials(i)-1)
                        currentDOFovershoots = [currentDOFovershoots S(index).overshoots(i,j,k)];
                        currentDOFMovementStart = [currentDOFMovementStart S(index).movementStart(i,j,k)];
                        currentDOFMovementSpeedAverages = [currentDOFMovementSpeedAverages S(index).movementSpeedTrialAverages(i,j,k)];
                    end
                end
                overshootsPerDOFmeans(k) = mean(currentDOFovershoots);
                overshootsPerDOFstderr(k) = std(currentDOFovershoots)/sqrt(sum(S(index).ntrials-(S(index).starttrials-1)));
                movementStartPerDOFmeans(k) = mean(currentDOFMovementStart);
                movementStartPerDOFstderr(k) = std(currentDOFMovementStart)/sqrt(sum(S(index).ntrials-(S(index).starttrials-1)));
                movementSpeedAveragePerDOFmeans(k) = mean(currentDOFMovementSpeedAverages);
                movementSpeedAveragePerDOFstderr(k) = std(currentDOFMovementSpeedAverages)/sqrt(sum(S(index).ntrials-(S(index).starttrials-1)));
            end      
        % Plot overshoots
            subplot(1,3,1);
            handle=barweb(overshootsPerDOFmeans,overshootsPerDOFstderr, [], [], 'Overshoots', [], 'Average Overshoots per Trial',[],[],S(index).dofLabels);
        
        % Plot Movement Start (note threshold)
            subplot(1,3,2);
            handle=barweb(movementStartPerDOFmeans,movementStartPerDOFstderr, [], [], 'Start of Movement', [], 's',[],[]);
        
        % Plot Avg Movement Speed
            subplot(1,3,3);
            handle=barweb(movementSpeedAveragePerDOFmeans,movementSpeedAveragePerDOFstderr, [], [], 'Average Movement Speed per Trial', [], '% of Range of Motion per Second',[],[]);
        end

        % Button pushed function: VelocitySpeedProfileButton
        function VelocitySpeedProfileButtonPushed(app, event)
            global x
            global S
            [~,index] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
            if size(index) > 1
                errordlg('select one log file');
            end
            % make sure the data for this index exists
            if ~S(index).loaded
                errordlg('error: data incomplete');
            end
            x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
            h = figure('Name', [char(x.EntriesListBox.Value) ' Velocity Profile'],'Position', x.figurePosition);
            velocityLimits = zeros(2, S(index).ndof);
                for k= 1: S(index).ndof
                    for i = 1:S(index).nblocks
                        for j = 1:S(index).ntrials(i)-(S(index).starttrials(i)-1)
                            %search for maxes and mins for plotting limits
                            velocityLimits(:,k) = [min([velocityLimits(1,k) S(index).movementSpeeds{i,j}(k,:)]); max([velocityLimits(2,k) S(index).movementSpeeds{i,j}(k,:)])];
                        end
                    end
                end

                S(index).tend = zeros(1, S(index).nblocks+1);
                
                % Normalized (0-100) movementSpeeds
                nMovementSpeeds = cell(S(index).nblocks, max([S(index).ntrials-(S(index).starttrials-1) diff(S(index).ntrials)]));
                for i = 1:S(index).nblocks
                    for j = 1:S(index).ntrials(i)-(S(index).starttrials(i)-1)
                        temp = [];
                        for k = 1:S(index).ndof
                            temp = [temp; ((S(index).movementSpeeds{i,j}(k,:)-velocityLimits(1,k))/(velocityLimits(2,k)-velocityLimits(1,k)))*100];
                        end
                        nMovementSpeeds{i,j} = temp;
                    end
                end
                
                % Each dof is a separate subplot
                for k=1:S(index).ndof
                    fillPlot = false;
                    S(index).toff = 0;
                    for i = 1:S(index).nblocks
                        for j = 1:S(index).ntrials(i)-(S(index).starttrials(i)-1)                            
                            subplot(S(index).ndof,1,k);
                            %plot shade to help distinguish trials
                            if fillPlot
                                jbfill(S(index).ct{i,j} + S(index).toff, 100*ones(1,length(S(index).ct{i,j} + S(index).toff)), zeros(1,length(S(index).ct{i,j}+S(index).toff)), [.35 .35 .75], 'none'); 
                                hold on;
                                fillPlot = false;
                            else
                                fillPlot = true;
                            end
                            %plot velocity profile
                            plot(S(index).ct{i,j} + S(index).toff, nMovementSpeeds{i,j}(k,:), 'k');
                            %plot 'X' at end of target if unsuccessful
                            plot(S(index).ct{i,j}+S(index).toff, S(index).ctout{i,j}.*nMovementSpeeds{i,j}(k,:), 'rx');
                            %adjust toffset by length of current trial
                            S(index).toff = S(index).ct{i,j}(end) + S(index).toff;
                        end
                        if k==1
                            title(['Velocity Profile for ' x.EntriesListBox.Value]);
                            %save the end time for the block (same for each dof)
                            S(index).tend(i+1) = S(index).toff;
                            %label the blocks (only above first subplot)
                            text(mean([S(index).tend(i) S(index).tend(i+1)]), 110, num2str(i), 'HorizontalAlignment', 'Center', 'Color', 'r')
                        end
                         %mark the beginning and end of each block on each subplot
                         plot([S(index).tend(i+1) S(index).tend(i+1)], [0 100], 'r');
                    end
                    %plot scale indicators for time and dof angles
                    plot([S(index).toff+10 S(index).toff+10], [0 100], 'k', 'LineWidth', 3); 
                    
                    text(S(index).toff+20, 0, [num2str(velocityLimits(1,k)) '% ROM/s'], 'VerticalAlignment', 'Middle', 'HorizontalAlignment', 'Left');
                    text(S(index).toff+20, 100, [num2str(velocityLimits(2,k)) '% ROM/s'], 'VerticalAlignment', 'Middle', 'HorizontalAlignment', 'Left');

                    %make axes slightly bigger than necessary to accomodate scale
                    %indicators (add space to right and below)
                    axis([0 S(index).toff+20 0-20 100]);  
                    axis off;
                    plot([0 60], [-10 -10], 'k', 'LineWidth', 3); 
                    text(30, -15, '60s', 'VerticalAlignment', 'Top', 'HorizontalAlignment', 'Center')
                    %label the subplot (can't use ylabel, because axis is hidden)
                    text(0, 50, S(index).dofLabels(k), 'VerticalAlignment', 'Middle', 'HorizontalAlignment', 'Right', 'FontWeight', 'bold')
                    %set background color to white (default is gray)
                    set(gcf, 'Color', [1 1 1])
                    hold off;
                end


        end

        % Button pushed function: CumulativeCompletionRateButton
        function CumulativeCompletionRateButtonPushed(app, event)
                global S
                global x
                x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
                h = figure('Name', 'Cumulative Completion Rates','Position', x.figurePosition);                        
                ylabel('% Trials Complete');
                xlabel('Seconds');
                hold on;
                legend(x.EntriesListBox.Value);
             for numlog = 1: size(x.EntriesListBox.Value, 2)
                [~, indicies] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                index = indicies(numlog);
                axis([0 S(index).timeout 0 100]);
                S(index).oneDimensionalTimeToTarget = [];
                for i = 1:S(index).nblocks
                    S(index).oneDimensionalTimeToTarget =[S(index).oneDimensionalTimeToTarget S(index).timetotarget(i,1:S(index).ntrials(i)-(S(index).starttrials(i)-1))];
                end
                sortedtimetotarget = sort(S(index).oneDimensionalTimeToTarget);
                y = zeros(1,sum(S(index).ntrials-(S(index).starttrials-1)));
                if length(sortedtimetotarget)~=length(y)
                    error('Dimensionality Error');
                end
                for i = 1:length(y)
                    y(i) = 100*(sum((sortedtimetotarget<=sortedtimetotarget(i)))/length(y));
                end
                clear oneDimensionalTimeToTarget;  
                scatter(sortedtimetotarget,y);
                 %FIX THIS
                
            end     
        end

        % Button pushed function: CumulativeVelocityProfileButton
        function CumulativeVelocityProfileButtonPushed(app, event)
              global x
              global S
            cumulativeMovementSpeeds = {};
            cumulativect = {};
            cumulativectout = {};
            cumulativechit = {};
            cumulativeVelocityProfilesLegendStrings = {};
            previousROM = false;
            previousndof = false;
            previousdofLabels = false;
            x.figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];
            cumulativeVelocityProfilesFig = figure('Name','Cumulative Velocity Profiles','Position',x.figurePosition);
            %cumulativeVelocityProfiles = true;
            
            button = questdlg('Normalize X Axes of Cumulative Velocity Profiles?');
            switch button
                case 'Yes'
                    NormalizeXAxis = true;
                case 'No'
                    NormalizeXAxis = false;
                case 'Cancel'
                    return;
            end
            set(0, 'CurrentFigure', cumulativeVelocityProfilesFig)

            if license('test', 'image_toolbox')
                if length(cumulativeMovementSpeeds) == 3
                    colorList = [ 0 0 1; 1 0 0; 0 0 0];
                else
                    colorList = distinguishable_colors(length(cumulativeMovementSpeeds));
                end
            else
                % Implement a color alternative
            end
            cumulativeVelocityLimits = {};
            
            for numlog = 1: size(x.EntriesListBox.Value, 2)
                [~, indicies] = ismember(x.EntriesListBox.Value, x.EntriesListBox.Items);
                index = indicies(numlog);
                velocityLimits = zeros(2,S(index).ndof);
                cumulativeVelocityLimits = [0 0 0; 0 0 0];
                for k=1:S(index).ndof
                    for i = 1:S(index).nblocks
                        for j = 1:S(index).ntrials(i)-(S(index).starttrials(i )-1)
                            %search for maxes and mins for plotting limits
                            velocityLimits(:,k) = [min([velocityLimits(1,k) S(index).movementSpeeds{i,j}(k,:)]); max([velocityLimits(2,k) S(index).movementSpeeds{i,j}(k,:)])];
                            cumulativeVelocityLimits(:,k) = [min([cumulativeVelocityLimits(1,k) velocityLimits(1,k)]); max([cumulativeVelocityLimits(2,k) velocityLimits(2,k)])];
                        end
                    end
                end
                cumulativeVelocityProfilesLegendStrings = [cumulativeVelocityProfilesLegendStrings S(index).alias];
                cumulativeMovementSpeeds{end+1}=S(index).movementSpeeds;
                cumulativect{end+1}=S(index).ct;
                cumulativectout{end+1}=S(index).ctout;
                cumulativechit{end+1}=S(index).chit;
                S(index).tend = zeros(1, S(index).nblocks+1);
                cumulativeVelocityProfilesLegendHandles = [];
            
                for log=1:length(cumulativeMovementSpeeds)
                    for k=1:S(index).ndof
                        S(index).toff = 0;
                        for i = 1:S(index).nblocks
                            for j = 1:S(index).ntrials(i)-(S(index).starttrials(i)-1) 
                                subplot(S(index).ndof,1,k);
                                hold on;
                            
                                X = [];
                                if NormalizeXAxis
                                    X = (cumulativect{log}{i,j} - min(cumulativect{log}{i,j}))/(max(cumulativect{log}{i,j})-min(cumulativect{log}{i,j}));
                                else
                                    X = cumulativect{log}{i,j};
                                end
                            
                            %plot velocity profile
                                if license('test', 'image_toolbox')
                                    h = plot(X+S(index).toff, cumulativeMovementSpeeds{log}{i,j}(k,:),'Color',colorList(log,:),'LineWidth',2);
                                
                                
                                end
                            
                            %plot 'X' at end of target if unsuccessful
                                failct = cumulativectout{log}{i,j}.*X;
                                failIndices = find(failct);
                                failct(isnan(failct)) = [];
                                failSpeeds = [];
                                for x = 1:length(failIndices)
                                    failSpeeds = [failSpeeds cumulativeMovementSpeeds{log}{i,j}(k,failIndices(x))];
                                end
                                if ~isempty(failct)
                                    plot(failct+S(index).toff, failSpeeds,'Color',colorList(log,:),'Marker','x');
                                end
                            
                            %plot 'O' at end of movement if successful
                                successct = cumulativechit{log}{i,j}.*X;
                                successIndices = find(successct);
                                successct(successct==0) = [];
                                successSpeeds = [];
                                for x = 1:length(successIndices)
                                    successSpeeds = [successSpeeds cumulativeMovementSpeeds{log}{i,j}(k,successIndices(x))];
                                end
                                if ~isempty(successct)
                                    plot(successct+ S(index).toff, successSpeeds,'Color',colorList(log,:),'Marker','o');
                                end

                            %adjust toffset by max trial length
                                if NormalizeXAxis
                                    S(index).toff = S(index).toff + 1;
                                else
                                    S(index).toff = S(index).toff + timeout;
                                end
                            end
                            if k==1 && log==1
                                title('Cumulative Velocity Profile Plot');
                                %save the end time for the block (same for each dof)
                                S(index).tend(i+1)=toff;
                                %label the blocks (only above first subplot)
                                text(mean([S(index).tend(i) S(index).tend(i+1)]), cumulativeVelocityLimits(2,k)+10, num2str(i), 'HorizontalAlignment', 'Center', 'Color', 'r')
                            end
                            if (log == 1)
                                %mark the beginning and end of each block on each subplot
                                plot([S(index).tend(i+1) S(index).tend(i+1)], [cumulativeVelocityLimits(1,k) cumulativeVelocityLimits(2,k)], 'r');
                            end
                        end
                    
                        if log==1
                            axis([0 S(index).toff+20 cumulativeVelocityLimits(1,k)-5 cumulativeVelocityLimits(2,k)+5]); % : ADJUST WITH SLIDER?
                            ylabel([S(index).dofLabels(k) ' %ROM/s']);
                            if k==S(index).ndof
                                if NormalizeXAxis
                                    xlabel('Trial');
                                else
                                xlabel('Seconds');
                                end
                            else
                                set(gca, 'XTickLabelMode', 'Manual')
                            end
                        end
                        hold off;
                    
                    end
                    cumulativeVelocityProfilesLegendHandles(end+1) = h;
                end
            end


                legend(cumulativeVelocityProfilesLegendHandles,cumulativeVelocityProfilesLegendStrings,'Location','northeast');
            
        end

        % Button pushed function: AverageVelocityProfilePlotButton
        function AverageVelocityProfilePlotButtonPushed(app, event)
            
        end

        % Button pushed function: SavealldatatodesktopButton
        function SavealldatatodesktopButtonPushed(app, event)
            global S
            save('all_data', S);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure1 = uifigure('Visible', 'off');
            app.UIFigure1.Name = 'UI Figure1';
            app.UIFigure1.CloseRequestFcn = createCallbackFcn(app, @UIFigure1CloseRequest, true);
            app.AddFileButton = uibutton(app.UIFigure1, 'push');
            app.AddFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddFileButtonPushed, true);
            app.AddFileButton.Text = 'Add File';
            app.EntriesListBoxLabel = uilabel(app.UIFigure1);
            app.EntriesListBoxLabel.Text = 'Entries';
            app.EntriesListBox = uilistbox(app.UIFigure1);
            app.EntriesListBox.Items = {};
            app.EntriesListBox.ValueChangedFcn = createCallbackFcn(app, @EntriesListBoxValueChanged, true);
            app.EntriesListBox.Value = {};
            app.DeleteFileButton = uibutton(app.UIFigure1, 'push');
            app.DeleteFileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFileButtonPushed, true);
            app.DeleteFileButton.Text = 'Delete File';
            app.MoveUpButton = uibutton(app.UIFigure1, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.UIFigure1, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.AliasforselectedfileEditFieldLabel = uilabel(app.UIFigure1);
            app.AliasforselectedfileEditFieldLabel.Text = 'Alias for selected file';
            app.AliasforselectedfileEditField = uieditfield(app.UIFigure1, 'text');
            app.AliasforselectedfileEditField.ValueChangedFcn = createCallbackFcn(app, @AliasforselectedfileEditFieldValueChanged, true);
            app.OpensecondGUIButton = uibutton(app.UIFigure1, 'push');
            app.OpensecondGUIButton.ButtonPushedFcn = createCallbackFcn(app, @OpensecondGUIButtonPushed, true);
            app.OpensecondGUIButton.Text = 'Open second GUI';
            app.CursorvsTargetButton = uibutton(app.UIFigure1, 'push');
            app.CursorvsTargetButton.ButtonPushedFcn = createCallbackFcn(app, @CursorvsTargetButtonPushed, true);
            app.CursorvsTargetButton.Text = 'Cursor vs Target';
            app.FigureGenerationLabel = uilabel(app.UIFigure1);
            app.FigureGenerationLabel.Text = 'Figure Generation:';
            app.LoadOrganizeFilesLabel = uilabel(app.UIFigure1);
            app.LoadOrganizeFilesLabel.Text = 'Load/Organize Files:';
            app.CompletionRatevsSecondsButton = uibutton(app.UIFigure1, 'push');
            app.CompletionRatevsSecondsButton.ButtonPushedFcn = createCallbackFcn(app, @CompletionRatevsSecondsButtonPushed, true);
            app.CompletionRatevsSecondsButton.Text = 'Completion Rate vs Seconds';
            app.SpeedHistogramButton = uibutton(app.UIFigure1, 'push');
            app.SpeedHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedHistogramButtonPushed, true);
            app.SpeedHistogramButton.Text = 'Speed Histogram';
            app.PostureMatchingMetricsButton = uibutton(app.UIFigure1, 'push');
            app.PostureMatchingMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @PostureMatchingMetricsButtonPushed, true);
            app.PostureMatchingMetricsButton.Text = 'Posture Matching Metrics';
            app.VelocitySpeedProfileButton = uibutton(app.UIFigure1, 'push');
            app.VelocitySpeedProfileButton.ButtonPushedFcn = createCallbackFcn(app, @VelocitySpeedProfileButtonPushed, true);
            app.VelocitySpeedProfileButton.Text = 'Velocity (Speed) Profile';
            app.CumulativeFiguresLabel = uilabel(app.UIFigure1);
            app.CumulativeFiguresLabel.Text = 'Cumulative Figures:';
            app.CumulativeCompletionRateButton = uibutton(app.UIFigure1, 'push');
            app.CumulativeCompletionRateButton.ButtonPushedFcn = createCallbackFcn(app, @CumulativeCompletionRateButtonPushed, true);
            app.CumulativeCompletionRateButton.Text = 'Cumulative Completion Rate';
            app.CumulativeSpeedHistogramButton = uibutton(app.UIFigure1, 'push');
            app.CumulativeSpeedHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @CumulativeSpeedHistogramButtonPushed, true);
            app.CumulativeSpeedHistogramButton.Text = 'Cumulative Speed Histogram';
            app.CumulativeMetricBoxplotsButton = uibutton(app.UIFigure1, 'push');
            app.CumulativeMetricBoxplotsButton.ButtonPushedFcn = createCallbackFcn(app, @CumulativeMetricBoxplotsButtonPushed, true);
            app.CumulativeMetricBoxplotsButton.Text = 'Cumulative Metric Boxplots';
            app.CumulativeVelocityProfileButton = uibutton(app.UIFigure1, 'push');
            app.CumulativeVelocityProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CumulativeVelocityProfileButtonPushed, true);
            app.CumulativeVelocityProfileButton.Text = 'Cumulative Velocity Profile';
            app.AverageVelocityProfilePlotButton = uibutton(app.UIFigure1, 'push');
            app.AverageVelocityProfilePlotButton.ButtonPushedFcn = createCallbackFcn(app, @AverageVelocityProfilePlotButtonPushed, true);
            app.AverageVelocityProfilePlotButton.Text = 'Average Velocity Profile Plot';
            app.GotuserinputLabel = uilabel(app.UIFigure1);
            app.GotuserinputLabel.Text = 'Got user input? ';
            app.InfoaboutselectedfileLabel = uilabel(app.UIFigure1);
            app.InfoaboutselectedfileLabel.Text = 'Info about selected file:';
            app.Label = uilabel(app.UIFigure1);
            app.Label.Text = '';
            app.NameofselectedfileLabel = uilabel(app.UIFigure1);
            app.NameofselectedfileLabel.Text = 'Name of selected file:';
            app.SavealldatatodesktopButton = uibutton(app.UIFigure1, 'push');
            app.SavealldatatodesktopButton.ButtonPushedFcn = createCallbackFcn(app, @SavealldatatodesktopButtonPushed, true);
            app.SavealldatatodesktopButton.Text = 'Save all data to desktop';
            % Show the figure after all components are created
            app.UIFigure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_LYU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure1)
        end
    end
end