classdef GUI_Linda2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure2                      matlab.ui.Figure
        DataforfiguregenerationLabel   matlab.ui.control.Label
        DOFLabelsPanel                 matlab.ui.container.Panel
        DOF1LabelEditFieldLabel        matlab.ui.control.Label
        DOF1LabelEditField             matlab.ui.control.EditField
        DOF2LabelEditFieldLabel        matlab.ui.control.Label
        DOF2LabelEditField             matlab.ui.control.EditField
        DOF3LabelEditFieldLabel        matlab.ui.control.Label
        DOF3LabelEditField             matlab.ui.control.EditField
        DOF4LabelEditFieldLabel        matlab.ui.control.Label
        DOF4LabelEditField             matlab.ui.control.EditField
        DOF5LabelEditFieldLabel        matlab.ui.control.Label
        DOF5LabelEditField             matlab.ui.control.EditField
        DOF6LabelEditFieldLabel        matlab.ui.control.Label
        DOF6LabelEditField             matlab.ui.control.EditField
        HandvisIndexPanel              matlab.ui.container.Panel
        DOF1HandvisIndex16EditFieldLabel  matlab.ui.control.Label
        DOF1HandvisIndex16EditField    matlab.ui.control.NumericEditField
        DOF2HandvisIndex16EditFieldLabel  matlab.ui.control.Label
        DOF2HandvisIndex16EditField    matlab.ui.control.NumericEditField
        DOF3HandvisIndex16EditFieldLabel  matlab.ui.control.Label
        DOF3HandvisIndex16EditField    matlab.ui.control.NumericEditField
        DOF4HandvisIndex16EditFieldLabel  matlab.ui.control.Label
        DOF4HandvisIndex16EditField    matlab.ui.control.NumericEditField
        DOF5HandvisIndex16EditFieldLabel  matlab.ui.control.Label
        DOF5HandvisIndex16EditField    matlab.ui.control.NumericEditField
        DOF6HandvisIndex16EditFieldLabel  matlab.ui.control.Label
        DOF6HandvisIndex16EditField    matlab.ui.control.NumericEditField
        LowerHigherBoundsPanel         matlab.ui.container.Panel
        DOF1LowerBoundEditFieldLabel   matlab.ui.control.Label
        DOF1LowerBoundEditField        matlab.ui.control.NumericEditField
        DOF1HigherBoundEditFieldLabel  matlab.ui.control.Label
        DOF1HigherBoundEditField       matlab.ui.control.NumericEditField
        DOF2LowerBoundEditFieldLabel   matlab.ui.control.Label
        DOF2LowerBoundEditField        matlab.ui.control.NumericEditField
        DOF2HigherBoundEditFieldLabel  matlab.ui.control.Label
        DOF2HigherBoundEditField       matlab.ui.control.NumericEditField
        DOF3LowerBoundEditFieldLabel   matlab.ui.control.Label
        DOF3LowerBoundEditField        matlab.ui.control.NumericEditField
        DOF3HigherBoundEditFieldLabel  matlab.ui.control.Label
        DOF3HigherBoundEditField       matlab.ui.control.NumericEditField
        DOF4LowerBoundEditFieldLabel   matlab.ui.control.Label
        DOF4LowerBoundEditField        matlab.ui.control.NumericEditField
        DOF4HigherBoundEditFieldLabel  matlab.ui.control.Label
        DOF4HigherBoundEditField       matlab.ui.control.NumericEditField
        DOF5LowerBoundEditFieldLabel   matlab.ui.control.Label
        DOF5LowerBoundEditField        matlab.ui.control.NumericEditField
        DOF5HigherBoundEditFieldLabel  matlab.ui.control.Label
        DOF5HigherBoundEditField       matlab.ui.control.NumericEditField
        DOF6LowerBoundEditFieldLabel   matlab.ui.control.Label
        DOF6LowerBoundEditField        matlab.ui.control.NumericEditField
        DOF6HigherBoundEditFieldLabel  matlab.ui.control.Label
        DOF6HigherBoundEditField       matlab.ui.control.NumericEditField
        ConfirmdatabelowButton         matlab.ui.control.Button
        NumbersofDOFEditFieldLabel     matlab.ui.control.Label
        NumbersofDOFEditField          matlab.ui.control.NumericEditField
    end


    properties (Access = private)
       
        
    end

    properties (Access = public)
        index; % index of the file (from GUI 1)
        nfiles % number of fully loaded files
        
    end

    methods (Access = private)
                
        
    end


    methods (Access = private)

        % Close request function: UIFigure2
        function UIFigure2CloseRequest(app, event)
           % global x
           % x.OpensecondGUIButton.Enable = 'on';
           global y
           delete(y)
           delete(app)
        end

        % Button pushed function: ConfirmdatabelowButton
        function ConfirmdatabelowButtonPushed(app, event)
             global y
             global S
             global x
             S(y.index).loaded = true;
             x.GotuserinputLabel.Text = 'Got user input? yes';


             %get ndof
             S(y.index).ndof = y.NumbersofDOFEditField.Value;
             
             %make dof labels
               
             firstMovementThreshold = 1; % what do i do with this
             
             if S(y.index).ndof < 1
                error('not enough degrees of freedom');
             elseif S(y.index).ndof > 6
                error('too many degrees of freedom');
             else
                ndof = S(y.index).ndof;
                switch ndof
                    case 3
                        S(y.index).dofLabels{1} = y.DOF1LabelEditField.Value;
                        S(y.index).dofLabels{2} = y.DOF2LabelEditField.Value;
                        S(y.index).dofLabels{3} = y.DOF3LabelEditField.Value;
                        S(y.index).truetimeIndex = 2;
                        S(y.index).DOF1LIndex = 3;
                        S(y.index).DOF2LIndex = 4;
                        S(y.index).DOF3LIndex = 5;
                        S(y.index).LSourceIndex = 6;
                        S(y.index).DOF1RIndex = 8;
                        S(y.index).DOF2RIndex = 9;
                        S(y.index).DOF3RIndex = 10;
                        S(y.index).RSourceIndex = 11;
                        S(y.index).DOF1ToleranceIndex = 13;
                        S(y.index).DOF2ToleranceIndex = 14;
                        S(y.index).DOF3ToleranceIndex = 15;
                        S(y.index).trialNumberIndex = 16;
                        S(y.index).blockNumberIndex = 17;
                        S(y.index).difficultyIndex = 18;
                        S(y.index).HitIndex = 19; 
                        S(y.index).LAST_DOFLIndex = S(y.index).DOF3LIndex;
                        S(y.index).LAST_DOFRIndex = S(y.index).DOF3RIndex;
                        S(y.index).LAST_DOFToleranceIndex = S(y.index).DOF3ToleranceIndex;
                    case 6
                        S(y.index).dofLabels{1} = y.DOF1LabelEditField.Value;
                        S(y.index).dofLabels{2} = y.DOF2LabelEditField.Value;
                        S(y.index).dofLabels{3} = y.DOF3LabelEditField.Value;
                        S(y.index).dofLabels{4} = y.DOF4LabelEditField.Value;              
                        S(y.index).dofLabels{5} = y.DOF5LabelEditField.Value;
                        S(y.index).dofLabels{6} = y.DOF6LabelEditField.Value;
                        S(y.index).truetimeIndex = 2;
                        S(y.index).DOF1LIndex = 3;
                        S(y.index).DOF2LIndex = 4;
                        S(y.index).DOF3LIndex = 5;
                        S(y.index).DOF4LIndex = 6;
                        S(y.index).DOF5LIndex = 7;
                        S(y.index).DOF6LIndex = 8;
                        S(y.index).LSourceIndex = 9;
                        S(y.index).DOF1RIndex = 11;
                        S(y.index).DOF2RIndex = 12;
                        S(y.index).DOF3RIndex = 13;
                        S(y.index).DOF4RIndex = 14;
                        S(y.index).DOF5RIndex = 15;
                        S(y.index).DOF6RIndex = 16;
                        S(y.index).RSourceIndex = 17;
                        S(y.index).DOF1ToleranceIndex = 19;
                        S(y.index).DOF2ToleranceIndex = 20;
                        S(y.index).DOF3ToleranceIndex = 21;
                        S(y.index).DOF4ToleranceIndex = 22;
                        S(y.index).DOF5ToleranceIndex = 23;
                        S(y.index).DOF6ToleranceIndex = 24;
                        S(y.index).trialNumberIndex = 25;
                        S(y.index).blockNumberIndex = 26;
                        S(y.index).difficultyIndex = 27;
                        S(y.index).HitIndex = 28;
                        S(y.index).LAST_DOFLIndex = S(y.index).DOF6LIndex;
                        S(y.index).LAST_DOFRIndex = S(y.index).DOF6RIndex;
                        S(y.index).LAST_DOFToleranceIndex = S(y.index).DOF6ToleranceIndex;
                end
             end
             
             % fill and check ROM
             temp = [y.DOF1LowerBoundEditField.Value, y.DOF2LowerBoundEditField.Value, y.DOF3LowerBoundEditField.Value, y.DOF4LowerBoundEditField.Value, y.DOF5LowerBoundEditField.Value, y.DOF6LowerBoundEditField.Value;
y.DOF1HigherBoundEditField.Value, y.DOF2HigherBoundEditField.Value, y.DOF3HigherBoundEditField.Value, y.DOF4HigherBoundEditField.Value, y.DOF5HigherBoundEditField.Value, y.DOF6HigherBoundEditField.Value];
            for i = 1:S(y.index).ndof
                for j = 1:2
                    S(y.index).ROM(j,i) = temp(j,i);
                end
            end
            if ~size(S(y.index).ROM,2) == S(y.index).ndof
                error('degrees of freedom do not match info given for bounds');
            end
            
            % data preparation


            S(y.index).t = S(y.index).data.handvis(S(y.index).truetimeIndex,:);
            fill = 1;
            if S(y.index).data.handvis(S(y.index).LSourceIndex,end) == 3 % Check handedness
                S(y.index).target = S(y.index).data.handvis([S(y.index).DOF1LIndex:S(y.index).LAST_DOFLIndex],:);
                S(y.index).cursor = S(y.index).data.handvis([S(y.index).DOF1RIndex:S(y.index).LAST_DOFRIndex],:);
            else
                if S(y.index).data.handvis(S(y.index).RSourceIndex,end) ~= 3
                    fill = 0; 
                end    
                S(y.index).target = S(y.index).data.handvis([S(y.index).DOF1RIndex:S(y.index).LAST_DOFRIndex],:);
                S(y.index).cursor = S(y.index).data.handvis([S(y.index).DOF1LIndex:S(y.index).LAST_DOFLIndex],:);
            end
            S(y.index).nsamp = size(S(y.index).data.handvis,2);
            S(y.index).trial = S(y.index).data.handvis(S(y.index).trialNumberIndex,:);
            S(y.index).block = S(y.index).data.handvis(S(y.index).blockNumberIndex,:);
            S(y.index).difficulty = S(y.index).data.handvis(S(y.index).difficultyIndex,:); % The tolerance of 15%
            if ~sum(S(y.index).difficulty)
                S(y.index).difficulty = 15*ones(size(S(y.index).difficulty));
            end
            S(y.index).hit = S(y.index).data.handvis(S(y.index).HitIndex,:); % 1=all DOF have been on target for required dwell time, indicates successful end of trial
            S(y.index).tol = S(y.index).data.handvis([S(y.index).DOF1ToleranceIndex:S(y.index).LAST_DOFToleranceIndex],:);
            S(y.index).timeout = 30; %TODO: Import settings?

            % Remove first few data points with incomplete trial or block numbering
            removeZeroesIndex = max(find(S(y.index).block,1),find(S(y.index).trial,1));
            if ~isempty(removeZeroesIndex)
                S(y.index).t = S(y.index).t(removeZeroesIndex:end);
                S(y.index).target = S(y.index).target(:,removeZeroesIndex:end);
                S(y.index).cursor = S(y.index).cursor(:,removeZeroesIndex:end);
                S(y.index).nsamp = S(y.index).nsamp - (removeZeroesIndex-1);
                S(y.index).trial = S(y.index).trial(removeZeroesIndex:end);
                S(y.index).block = S(y.index).block(removeZeroesIndex:end);
                S(y.index).hit = S(y.index).hit(removeZeroesIndex:end);
                S(y.index).tol = S(y.index).tol(:,removeZeroesIndex:end);
                S(y.index).difficulty = S(y.index).difficulty(removeZeroesIndex:end);
            end

            % Normalize block and trial numbering
            S(y.index).trial = S(y.index).trial-(S(y.index).trial(1)-1);
            S(y.index).block = S(y.index).block-(S(y.index).block(1)-1);
            S(y.index).nblocks = max(S(y.index).block);
            S(y.index).ntrials = [];
            S(y.index).starttrials = []; 
            for i=1:S(y.index).nblocks
                S(y.index).ntrials(i) = max(S(y.index).trial(find(S(y.index).block==i)));
                S(y.index).starttrials(i) = min(S(y.index).trial(find(S(y.index).block==i)));
            end

                        %% Break data up into blocks (rows) and trials (columns)
            S(y.index).ct = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)]));
            S(y.index).ctarget = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)]));
            S(y.index).ccursor = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)]));
            S(y.index).nctarget = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)])); % Target normalized to ROM (0-100)
            S(y.index).nccursor = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)])); % Cursor normalized to ROM (0-100)
            S(y.index).ctol = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)])); % Tolerance in ROM by degrees (18, 18, and 15)
            S(y.index).chit = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)])); % 1 to hit
            S(y.index).cdifficulty = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)])); % Tolerance of 15%
            S(y.index).ctout = cell(S(y.index).nblocks, max([(S(y.index).ntrials-S(y.index).starttrials) diff(S(y.index).ntrials)]));
            for i=1:S(y.index).nblocks
               for j=1:S(y.index).ntrials(i)-(S(y.index).starttrials(i)-1) 
                   k = intersect(find(S(y.index).trial == (j+(S(y.index).starttrials(i)-1))),find(S(y.index).block == i));
                   S(y.index).ct{i,j} = S(y.index).t(k);
                   S(y.index).ct{i,j} = S(y.index).ct{i,j}-S(y.index).ct{i,j}(1);
                   S(y.index).ctarget{i,j} = S(y.index).target(:,k);
                   S(y.index).ccursor{i,j} = S(y.index).cursor(:,k);
                   %normalized by ROM to 0-100
                   S(y.index).nctarget{i,j} = (S(y.index).target(:,k)-repmat(S(y.index).ROM(1,:)',1, length(k)))./repmat(S(y.index).ROM(2,:)'-S(y.index).ROM(1,:)',1,length(k))*100;
                   S(y.index).nccursor{i,j} = (S(y.index).cursor(:,k)-repmat(S(y.index).ROM(1,:)',1, length(k)))./repmat(S(y.index).ROM(2,:)'-S(y.index).ROM(1,:)',1,length(k))*100;
                   S(y.index).ctol{i,j} = S(y.index).tol(:,k);
                   S(y.index).chit{i,j} = S(y.index).hit(:,k);
                   S(y.index).cdifficulty{i,j} = S(y.index).difficulty(k);

                   S(y.index).ctout{i,j} = nan(size(S(y.index).ct{i,j}));
                   if ~S(y.index).chit{i,j}(end) 
                       S(y.index).ctout{i,j}(end) =  1;
                   end
               end
            end
 %% Calculate metrics using normalized cursor/targets, including overshoots
            S(y.index).patheff = zeros(S(y.index).nblocks, max([S(y.index).ntrials-(S(y.index).starttrials-1) diff(S(y.index).ntrials)]));
            S(y.index).movementSpeeds = cell(S(y.index).nblocks, max([S(y.index).ntrials-(S(y.index).starttrials-1) diff(S(y.index).ntrials)]));
            S(y.index).movementSpeedTrialAverages = zeros(S(y.index).nblocks, max([S(y.index).ntrials-(S(y.index).starttrials-1) diff(S(y.index).ntrials)]), S(y.index).ndof);
            S(y.index).tolerance = zeros(S(y.index).nblocks,S(y.index).ndof);
            S(y.index).difficulty = zeros(S(y.index).nblocks,1);
            S(y.index).success = zeros(S(y.index).nblocks, max([S(y.index).ntrials-(S(y.index).starttrials-1) diff(S(y.index).ntrials)]));
            S(y.index).timetotarget = zeros(S(y.index).nblocks, max([S(y.index).ntrials-(S(y.index).starttrials-1) diff(S(y.index).ntrials)]));
            S(y.index).successrate = zeros(S(y.index).nblocks, 1);
            S(y.index).overshoots = zeros(S(y.index).nblocks, max([S(y.index).ntrials-(S(y.index).starttrials-1) diff(S(y.index).ntrials)]), S(y.index).ndof);
            S(y.index).movementStart = zeros(S(y.index).nblocks, max([S(y.index).ntrials-(S(y.index).starttrials-1) diff(S(y.index).ntrials)]), S(y.index).ndof);
            for i=1:S(y.index).nblocks
                for j = 1:S(y.index).ntrials(i)-(S(y.index).starttrials(i)-1)
                    if S(y.index).chit{i,j}(end)
                        S(y.index).success(i,j) = 1;
                    end
                    %S(y.index).timetotarget is aS(y.index).ctually the trial time...if they didn't reach target, S(y.index).timetotarget=timeout 
                    S(y.index).timetotarget(i,j) = S(y.index).ct{i,j}(end);

                    %Get the aS(y.index).ctual path length
                    pathdiff = abs(diff(S(y.index).nccursor{i,j},1,2)); %first order difference across rows
                    %pathdiff(pathdiff > 180) = 360 - pathdiff(pathdiff > 180);  %account for infinite wrist rotation
                    %first get the length for each segment, then sum up lengths
                    pathlength = sum(sqrt(sum(pathdiff.^2))); 

                    %S(y.index).movementSpeeds{i,j} = [[0;0;0] pathdiff./[diff(S(y.index).ct{i,j});diff(S(y.index).ct{i,j});diff(S(y.index).ct{i,j})]];
                    S(y.index).movementSpeeds{i,j} = [zeros(S(y.index).ndof,1) pathdiff./repmat(diff(S(y.index).ct{i,j}),S(y.index).ndof,1)];
                    %S(y.index).movementSpeedTrialAverages(i,j,:) = mean(pathdiff./[diff(S(y.index).ct{i,j});diff(S(y.index).ct{i,j});diff(S(y.index).ct{i,j})],2);
                    S(y.index).movementSpeedTrialAverages(i,j,:) = mean(pathdiff./repmat(diff(S(y.index).ct{i,j}),S(y.index).ndof,1),2);
                    
                    %Get the minimum pathlength
                    pathdiff = abs(S(y.index).nctarget{i,j}(:,1) - S(y.index).nccursor{i,j}(:,1));
                    %pathdiff(pathdiff > 180) = 360 - pathdiff(pathdiff > 180);  %account for infinite wrist rotation 
                    minpathlength = sqrt(sum(pathdiff.^2)); 

                    if ~S(y.index).success(i,j)
                        %get distance between target and cursor at end of trial
                        initialdist = minpathlength;
                        finaldist = sqrt(sum((S(y.index).nctarget{i,j}(:,end) - S(y.index).nccursor{i,j}(:,end)).^2));
                        if initialdist == finaldist %avoid divide by 0
                            pathlength = 0; %no movement occured
                        else
                            %assume pathlength (had they reached target)
                            pathlength = pathlength * initialdist/(initialdist-finaldist);
                            %if initialdist<finaldist, then pathlength will be negative,
                            %and S(y.index).patheff will be negative
                        end
                    end

                    if pathlength == 0 %avoid divide by 0
                        S(y.index).patheff(i,j) = 0;
                    else
                        S(y.index).patheff(i,j) = minpathlength/pathlength*100;
                        %saturate at 0 and 100%
                        S(y.index).patheff(i,j) = min(S(y.index).patheff(i,j), 100); 
                        S(y.index).patheff(i,j) = max(S(y.index).patheff(i,j), 0); 
                    end
                    %Alternative: Path inefficiency (work wasted): S(y.index).patheff(i,j) = max((pathlength-minpathlength)/minpathlength*100, 0); 

                    % Calculate S(y.index).overshoots and the first sign of movement
                    for k = 1:S(y.index).ndof
                        % First movement
                        if ~isempty(find(S(y.index).movementSpeeds{i,j}(k,:)>firstMovementThreshold,1))
                            S(y.index).movementStart(i,j,k) = find(S(y.index).movementSpeeds{i,j}(k,:)>firstMovementThreshold,1);
                        else
                            S(y.index).movementStart(i,j,k) = 1;
                        end
                        S(y.index).movementStart(i,j,k) = S(y.index).ct{i,j}(S(y.index).movementStart(i,j,k));
                        
                        % S(y.index).overshoots
                        inRange = ((S(y.index).nccursor{i,j}(k,1)<=S(y.index).nctarget{i,j}(k,1)+S(y.index).cdifficulty{i,j}(1))&&(S(y.index).nccursor{i,j}(k,1)>=S(y.index).nctarget{i,j}(k,1)-S(y.index).cdifficulty{i,j}(1)));
                        previousSide = sign(S(y.index).nccursor{i,j}(k,1)-S(y.index).nctarget{i,j}(k,1))*(~inRange); 
                        for index = 1:length(S(y.index).nccursor{i,j})
                            inRange = ((S(y.index).nccursor{i,j}(k,index)<=S(y.index).nctarget{i,j}(k,index)+S(y.index).cdifficulty{i,j}(index))&&(S(y.index).nccursor{i,j}(k,index)>=S(y.index).nctarget{i,j}(k,index)-S(y.index).cdifficulty{i,j}(index)));
                            currentSide = sign(S(y.index).nccursor{i,j}(k,index)-S(y.index).nctarget{i,j}(k,index))*(~inRange);
                            if (previousSide==0)
                                previousSide = currentSide;
                            elseif ~inRange&&(currentSide~=previousSide)
                                S(y.index).overshoots(i,j,k) = S(y.index).overshoots(i,j,k)+1;
                                previousSide = currentSide;
                            end
                        end
                    end
                    
               end
               S(y.index).tolerance(i,:)= S(y.index).ctol{i,1}(:,1)';
               S(y.index).difficulty(i)= S(y.index).cdifficulty{i,1}(1);
            end
            timeuse = S(y.index).timetotarget/S(y.index).timeout*100;

            %% Calculate averaged metrics
            S(y.index).oneDimensionalPathEff = [];
                S(y.index).oneDimensionalTimeToTarget = []; %CHANGE THIS BACK TO timeuse if wanting it as a percent
                S(y.index).oneDimensionalTrialAvgMovementSpeedDOFAveraged = [];
                S(y.index).oneDimensionalOvershootDOFSummed = [];
                S(y.index).oneDimensionalSuccess = [];
            
            for i = 1:S(y.index).nblocks
                S(y.index).oneDimensionalPathEff = [S(y.index).oneDimensionalPathEff S(y.index).patheff(i,1:S(y.index).ntrials(i)-(S(y.index).starttrials(i)-1))];
                S(y.index).oneDimensionalTimeToTarget = [S(y.index).oneDimensionalTimeToTarget S(y.index).timetotarget(i,1:S(y.index).ntrials(i)-(S(y.index).starttrials(i)-1))]; %CHANGE THIS BACK TO timeuse if wanting it as a percent
                S(y.index).oneDimensionalTrialAvgMovementSpeedDOFAveraged = [S(y.index).oneDimensionalTrialAvgMovementSpeedDOFAveraged mean(S(y.index).movementSpeedTrialAverages(i,1:S(y.index).ntrials(i)-(S(y.index).starttrials(i)-1),:),3)];
                S(y.index).oneDimensionalOvershootDOFSummed = [S(y.index).oneDimensionalOvershootDOFSummed sum(S(y.index).overshoots(i,1:S(y.index).ntrials(i)-(S(y.index).starttrials(i)-1),:),3)];
                S(y.index).oneDimensionalSuccess = [S(y.index).oneDimensionalSuccess S(y.index).success(i,1:S(y.index).ntrials(i)-(S(y.index).starttrials(i)-1))];
            end
            S(y.index).meanpatheff = mean(S(y.index).oneDimensionalPathEff);
            S(y.index).stdvpatheff = std(S(y.index).oneDimensionalPathEff);
            S(y.index).meantimetotarget = mean(S(y.index).oneDimensionalTimeToTarget);
            S(y.index).stdvtimetotarget = std(S(y.index).oneDimensionalTimeToTarget);
            S(y.index).meanmovementspeed = mean(S(y.index).oneDimensionalTrialAvgMovementSpeedDOFAveraged);
            S(y.index).stdvmovementspeed = std(S(y.index).oneDimensionalTrialAvgMovementSpeedDOFAveraged);
            S(y.index).meanovershoot = mean(S(y.index).oneDimensionalOvershootDOFSummed);
            S(y.index).stdvovershoot = std(S(y.index).oneDimensionalOvershootDOFSummed);
            S(y.index).successrate = sum(S(y.index).oneDimensionalSuccess)/sum(S(y.index).ntrials-(S(y.index).starttrials-1))*100;
    
           
            x.OpensecondGUIButton.Enable = 'on';
        % set figure position
        %    S(y.index).figurePosition = [0 50 x.screenSize(3) x.screenSize(4)-50-75];


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure2 = uifigure;
            app.UIFigure2.Name = 'UI Figure2';
            app.UIFigure2.CloseRequestFcn = createCallbackFcn(app, @UIFigure2CloseRequest, true);
            app.DataforfiguregenerationLabel = uilabel(app.UIFigure2);
            app.DataforfiguregenerationLabel.Text = 'Data for figure generation';
            app.DOFLabelsPanel = uipanel(app.UIFigure2);
            app.DOFLabelsPanel.Title = 'DOF Labels';
            app.DOF1LabelEditFieldLabel = uilabel(app.DOFLabelsPanel);
            app.DOF1LabelEditFieldLabel.Text = 'DOF 1 Label';
            app.DOF1LabelEditField = uieditfield(app.DOFLabelsPanel, 'text');
            app.DOF1LabelEditField.Value = 'enter label name';
            app.DOF2LabelEditFieldLabel = uilabel(app.DOFLabelsPanel);
            app.DOF2LabelEditFieldLabel.Text = 'DOF 2 Label';
            app.DOF2LabelEditField = uieditfield(app.DOFLabelsPanel, 'text');
            app.DOF2LabelEditField.Value = 'enter label name';
            app.DOF3LabelEditFieldLabel = uilabel(app.DOFLabelsPanel);
            app.DOF3LabelEditFieldLabel.Text = 'DOF 3 Label';
            app.DOF3LabelEditField = uieditfield(app.DOFLabelsPanel, 'text');
            app.DOF3LabelEditField.Value = 'enter label name';
            app.DOF4LabelEditFieldLabel = uilabel(app.DOFLabelsPanel);
            app.DOF4LabelEditFieldLabel.Text = 'DOF 4 Label';
            app.DOF4LabelEditField = uieditfield(app.DOFLabelsPanel, 'text');
            app.DOF4LabelEditField.Value = 'enter label name';
            app.DOF5LabelEditFieldLabel = uilabel(app.DOFLabelsPanel);
            app.DOF5LabelEditFieldLabel.Text = 'DOF 5 Label';
            app.DOF5LabelEditField = uieditfield(app.DOFLabelsPanel, 'text');
            app.DOF5LabelEditField.Value = 'enter label name';
            app.DOF6LabelEditFieldLabel = uilabel(app.DOFLabelsPanel);
            app.DOF6LabelEditFieldLabel.Text = 'DOF 6 Label';
            app.DOF6LabelEditField = uieditfield(app.DOFLabelsPanel, 'text');
            app.DOF6LabelEditField.Value = 'enter label name';
            app.HandvisIndexPanel = uipanel(app.UIFigure2);
            app.HandvisIndexPanel.Title = 'HandvisIndex';
            app.DOF1HandvisIndex16EditFieldLabel = uilabel(app.HandvisIndexPanel);
            app.DOF1HandvisIndex16EditFieldLabel.Text = 'DOF 1 (HandvisIndex 1-6)';
            app.DOF1HandvisIndex16EditField = uieditfield(app.HandvisIndexPanel, 'numeric');
            app.DOF1HandvisIndex16EditField.Value = 1;
            app.DOF2HandvisIndex16EditFieldLabel = uilabel(app.HandvisIndexPanel);
            app.DOF2HandvisIndex16EditFieldLabel.Text = 'DOF 2 (HandvisIndex 1-6)';
            app.DOF2HandvisIndex16EditField = uieditfield(app.HandvisIndexPanel, 'numeric');
            app.DOF2HandvisIndex16EditField.Value = 2;
            app.DOF3HandvisIndex16EditFieldLabel = uilabel(app.HandvisIndexPanel);
            app.DOF3HandvisIndex16EditFieldLabel.Text = 'DOF 3 (HandvisIndex 1-6)';
            app.DOF3HandvisIndex16EditField = uieditfield(app.HandvisIndexPanel, 'numeric');
            app.DOF3HandvisIndex16EditField.Value = 4;
            app.DOF4HandvisIndex16EditFieldLabel = uilabel(app.HandvisIndexPanel);
            app.DOF4HandvisIndex16EditFieldLabel.Text = 'DOF 4 (HandvisIndex 1-6)';
            app.DOF4HandvisIndex16EditField = uieditfield(app.HandvisIndexPanel, 'numeric');
            app.DOF4HandvisIndex16EditField.Value = 6;
            app.DOF5HandvisIndex16EditFieldLabel = uilabel(app.HandvisIndexPanel);
            app.DOF5HandvisIndex16EditFieldLabel.Text = 'DOF 5 (HandvisIndex 1-6)';
            app.DOF5HandvisIndex16EditField = uieditfield(app.HandvisIndexPanel, 'numeric');
            app.DOF5HandvisIndex16EditField.Value = 5;
            app.DOF6HandvisIndex16EditFieldLabel = uilabel(app.HandvisIndexPanel);
            app.DOF6HandvisIndex16EditFieldLabel.Text = 'DOF 6 (HandvisIndex 1-6)';
            app.DOF6HandvisIndex16EditField = uieditfield(app.HandvisIndexPanel, 'numeric');
            app.DOF6HandvisIndex16EditField.Value = 4;
            app.LowerHigherBoundsPanel = uipanel(app.UIFigure2);
            app.LowerHigherBoundsPanel.Title = 'Lower/Higher Bounds';
            app.DOF1LowerBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF1LowerBoundEditFieldLabel.Text = 'DOF1 Lower Bound';
            app.DOF1LowerBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF1HigherBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF1HigherBoundEditFieldLabel.Text = 'DOF1 Higher Bound';
            app.DOF1HigherBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF1HigherBoundEditField.Value = 180;
            app.DOF2LowerBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF2LowerBoundEditFieldLabel.Text = 'DOF2 Lower Bound';
            app.DOF2LowerBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF2LowerBoundEditField.Value = -60;
            app.DOF2HigherBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF2HigherBoundEditFieldLabel.Text = 'DOF2 Higher Bound';
            app.DOF2HigherBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF2HigherBoundEditField.Value = 70;
            app.DOF3LowerBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF3LowerBoundEditFieldLabel.Text = 'DOF3 Lower Bound';
            app.DOF3LowerBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF3HigherBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF3HigherBoundEditFieldLabel.Text = 'DOF3 Higher Bound';
            app.DOF3HigherBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF3HigherBoundEditField.Value = 100;
            app.DOF4LowerBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF4LowerBoundEditFieldLabel.Text = 'DOF4 Lower Bound';
            app.DOF4LowerBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF4HigherBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF4HigherBoundEditFieldLabel.Text = 'DOF4 Higher Bound';
            app.DOF4HigherBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF4HigherBoundEditField.Value = 100;
            app.DOF5LowerBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF5LowerBoundEditFieldLabel.Text = 'DOF5 Lower Bound';
            app.DOF5LowerBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF5HigherBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF5HigherBoundEditFieldLabel.Text = 'DOF5 Higher Bound';
            app.DOF5HigherBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF5HigherBoundEditField.Value = 100;
            app.DOF6LowerBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF6LowerBoundEditFieldLabel.Text = 'DOF6 Lower Bound';
            app.DOF6LowerBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF6HigherBoundEditFieldLabel = uilabel(app.LowerHigherBoundsPanel);
            app.DOF6HigherBoundEditFieldLabel.Text = 'DOF6 Higher Bound';
            app.DOF6HigherBoundEditField = uieditfield(app.LowerHigherBoundsPanel, 'numeric');
            app.DOF6HigherBoundEditField.Value = 100;
            app.ConfirmdatabelowButton = uibutton(app.UIFigure2, 'push');
            app.ConfirmdatabelowButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmdatabelowButtonPushed, true);
            app.ConfirmdatabelowButton.Text = 'Confirm data below';
            app.NumbersofDOFEditFieldLabel = uilabel(app.UIFigure2);
            app.NumbersofDOFEditFieldLabel.Text = 'Numbers of DOF';
            app.NumbersofDOFEditField = uieditfield(app.UIFigure2, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_Linda2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure2)
        end
    end
end